classdef App_PhaseLock < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhaseLockControlUIFigure       matlab.ui.Figure
        FrequencySettingsPanel         matlab.ui.container.Panel
        CenterFreqMHzEditFieldLabel    matlab.ui.control.Label
        CenterFreqMHzEditField         matlab.ui.control.NumericEditField
        DifferenceFreqMHzEditFieldLabel  matlab.ui.control.Label
        DifferenceFreqMHzEditField     matlab.ui.control.NumericEditField
        DemodFreqMHzEditFieldLabel     matlab.ui.control.Label
        DemodFreqMHzEditField          matlab.ui.control.NumericEditField
        DiffFreqShiftlog2SpinnerLabel  matlab.ui.control.Label
        DiffFreqShiftlog2Spinner       matlab.ui.control.Spinner
        UseDemodFreqCheckBox           matlab.ui.control.CheckBox
        UseManualFreqCheckBox          matlab.ui.control.CheckBox
        UseTCDemodCheckBox             matlab.ui.control.CheckBox
        Amplitude1VEditFieldLabel      matlab.ui.control.Label
        Amplitude1VEditField           matlab.ui.control.NumericEditField
        DisableexttrigCheckBox         matlab.ui.control.CheckBox
        Amplitude2VEditFieldLabel      matlab.ui.control.Label
        Amplitude2VEditField           matlab.ui.control.NumericEditField
        PhaseCalculationPanel          matlab.ui.container.Panel
        CICRatelog2SpinnerLabel        matlab.ui.control.Label
        CICRatelog2Spinner             matlab.ui.control.Spinner
        PhasesamplerateHzEditFieldLabel  matlab.ui.control.Label
        PhasesamplerateHzEditField     matlab.ui.control.NumericEditField
        PhasesampleperiodsEditFieldLabel  matlab.ui.control.Label
        PhasesampleperiodsEditField    matlab.ui.control.NumericEditField
        CICShiftSpinnerLabel           matlab.ui.control.Label
        CICShiftSpinner                matlab.ui.control.Spinner
        LockingparametersPanel         matlab.ui.container.Panel
        ControlphasedegEditFieldLabel  matlab.ui.control.Label
        ControlphasedegEditField       matlab.ui.control.NumericEditField
        KpSpinnerLabel                 matlab.ui.control.Label
        KpSpinner                      matlab.ui.control.Spinner
        KiSpinnerLabel                 matlab.ui.control.Label
        KiSpinner                      matlab.ui.control.Spinner
        KdSpinnerLabel                 matlab.ui.control.Label
        KdSpinner                      matlab.ui.control.Spinner
        DivisorSpinnerLabel            matlab.ui.control.Label
        DivisorSpinner                 matlab.ui.control.Spinner
        KpRealEditField                matlab.ui.control.NumericEditField
        KiRealEditField                matlab.ui.control.NumericEditField
        KdRealEditField                matlab.ui.control.NumericEditField
        EnableButton                   matlab.ui.control.StateButton
        PolarityDropDownLabel          matlab.ui.control.Label
        PolarityDropDown               matlab.ui.control.DropDown
        ControlPhaseRadEditField       matlab.ui.control.NumericEditField
        UploadButton                   matlab.ui.control.Button
        FetchButton                    matlab.ui.control.Button
        UploadonchangeCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        pl % Description
    end
    
    methods (Access = private)
        
        function results = autoload(app)
            if app.UploadonchangeCheckBox.Value
                app.pl.upload;
            end
            app.updateFields;
        end
        
        function results = updateFields(app)
            app.CenterFreqMHzEditField.Value = app.pl.f0.value;
            app.DifferenceFreqMHzEditField.Value = app.pl.df.value;
            app.DemodFreqMHzEditField.Value = app.pl.demod.value;
            app.DiffFreqShiftlog2Spinner.Value = app.pl.shift.value;
            app.UseDemodFreqCheckBox.Value = logical(app.pl.useSetDemod.value);
            app.UseManualFreqCheckBox.Value = logical(app.pl.useManual.value);
            app.UseTCDemodCheckBox.Value = logical(app.pl.useTCDemod.value);
            app.DisableexttrigCheckBox.Value = logical(app.pl.disableExtTrig.value);
            app.Amplitude1VEditField.Value = app.pl.amp(1).value;
            app.Amplitude2VEditField.Value = app.pl.amp(2).value;
            
            app.CICRatelog2Spinner.Value = app.pl.cicRate.value;
            app.CICShiftSpinner.Value = app.pl.cicShift.value;
            app.PhasesamplerateHzEditField.Value = 1/app.pl.dt;
            app.PhasesampleperiodsEditField.Value = 1/app.PhasesamplerateHzEditField.Value;
            
            app.ControlphasedegEditField.Value = app.pl.phasec.value*180/pi;
            app.ControlPhaseRadEditField.Value = app.pl.phasec.value;
            app.KpSpinner.Value = app.pl.Kp.value;
            app.KiSpinner.Value = app.pl.Ki.value;
            app.KdSpinner.Value = app.pl.Kd.value;
            app.DivisorSpinner.Value = app.pl.divisor.value;
            [Kp,Ki,Kd] = app.pl.calcRealGains;
            app.KpRealEditField.Value = Kp;
            app.KiRealEditField.Value = Ki;
            app.KdRealEditField.Value = Kd;
            
            app.EnableButton.Value = logical(app.pl.enableFB.value);
            if app.pl.polarity.value
                s = 'Positive';
            else
                s = 'Negative';
            end
            app.PolarityDropDown.Value = s;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pl)
            app.pl = pl;
            try 
                app.updateFields
            catch ME
                app.pl.setDefaults;
                app.updateFields;
            end
        end

        % Value changed function: CenterFreqMHzEditField
        function CenterFreqMHzEditFieldValueChanged(app, event)
            value = app.CenterFreqMHzEditField.Value;
            app.pl.f0.set(value);
            app.autoload;
        end

        % Value changed function: DifferenceFreqMHzEditField
        function DifferenceFreqMHzEditFieldValueChanged(app, event)
            value = app.DifferenceFreqMHzEditField.Value;
            app.pl.df.set(value);
            app.autoload;
        end

        % Value changed function: DemodFreqMHzEditField
        function DemodFreqMHzEditFieldValueChanged(app, event)
            value = app.DemodFreqMHzEditField.Value;
            app.pl.demod.set(value);
            app.autoload;
        end

        % Value changed function: DiffFreqShiftlog2Spinner
        function DiffFreqShiftlog2SpinnerValueChanged(app, event)
            value = app.DiffFreqShiftlog2Spinner.Value;
            app.pl.shift.set(value);
            app.autoload;
        end

        % Value changed function: UseDemodFreqCheckBox
        function UseDemodFreqCheckBoxValueChanged(app, event)
            value = app.UseDemodFreqCheckBox.Value;
            app.pl.useSetDemod.set(value);
            app.autoload;
        end

        % Value changed function: UseManualFreqCheckBox
        function UseManualFreqCheckBoxValueChanged(app, event)
            value = app.UseManualFreqCheckBox.Value;
            app.pl.useManual.set(value);
            app.autoload;
        end

        % Value changed function: ControlphasedegEditField
        function ControlphasedegEditFieldValueChanged(app, event)
            value = app.ControlphasedegEditField.Value;
            app.pl.phasec.set(value*pi/180);
            app.autoload;
        end

        % Value changed function: KpSpinner
        function KpSpinnerValueChanged(app, event)
            value = app.KpSpinner.Value;
            app.pl.Kp.set(value);
            app.autoload;
        end

        % Value changed function: KiSpinner
        function KiSpinnerValueChanged(app, event)
            value = app.KiSpinner.Value;
            app.pl.Ki.set(value);
            app.autoload;
        end

        % Value changed function: KdSpinner
        function KdSpinnerValueChanged(app, event)
            value = app.KdSpinner.Value;
            app.pl.Kd.set(value);
            app.autoload;
        end

        % Value changed function: DivisorSpinner
        function DivisorSpinnerValueChanged(app, event)
            value = app.DivisorSpinner.Value;
            app.pl.divisor.set(value);
            app.autoload;
        end

        % Value changed function: EnableButton
        function EnableButtonValueChanged(app, event)
            value = app.EnableButton.Value;
            app.pl.enableFB.set(value);
            app.autoload;
        end

        % Value changed function: PolarityDropDown
        function PolarityDropDownValueChanged(app, event)
            value = app.PolarityDropDown.Value;
            if strcmpi(value,'negative')
                s = 0;
            elseif strcmpi(value,'positive')
                s = 1;
            end
            app.pl.polarity.set(s);
            app.autoload;
        end

        % Value changed function: CICRatelog2Spinner
        function CICRatelog2SpinnerValueChanged(app, event)
            value = app.CICRatelog2Spinner.Value;
            app.pl.cicRate.set(value);
            app.autoload;
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.pl.upload;
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            app.pl.fetch;
            app.updateFields;
        end

        % Value changed function: UseTCDemodCheckBox
        function UseTCDemodCheckBoxValueChanged(app, event)
            value = app.UseTCDemodCheckBox.Value;
            app.pl.useTCDemod.set(value);
            app.autoload;
        end

        % Value changed function: Amplitude1VEditField
        function Amplitude1VEditFieldValueChanged(app, event)
            value = app.Amplitude1VEditField.Value;
            app.pl.amp(1).set(value);
            app.autoload;
        end

        % Value changed function: CICShiftSpinner
        function CICShiftSpinnerValueChanged(app, event)
            value = app.CICShiftSpinner.Value;
            app.pl.cicShift.set(value);
            app.autoload;
        end

        % Value changed function: DisableexttrigCheckBox
        function DisableexttrigCheckBoxValueChanged(app, event)
            value = app.DisableexttrigCheckBox.Value;
            app.pl.disableExtTrig.set(value);
            app.autoload;
        end

        % Value changed function: Amplitude2VEditField
        function Amplitude2VEditFieldValueChanged(app, event)
            value = app.Amplitude2VEditField.Value;
            app.pl.amp(2).set(value);
            app.autoload;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhaseLockControlUIFigure = uifigure('Visible', 'off');
            app.PhaseLockControlUIFigure.Name = 'Phase Lock Control';
            app.FrequencySettingsPanel = uipanel(app.PhaseLockControlUIFigure);
            app.FrequencySettingsPanel.Title = 'Frequency Settings';
            app.CenterFreqMHzEditFieldLabel = uilabel(app.FrequencySettingsPanel);
            app.CenterFreqMHzEditFieldLabel.Text = 'Center Freq. [MHz]';
            app.CenterFreqMHzEditField = uieditfield(app.FrequencySettingsPanel, 'numeric');
            app.CenterFreqMHzEditField.Limits = [0 50];
            app.CenterFreqMHzEditField.ValueChangedFcn = createCallbackFcn(app, @CenterFreqMHzEditFieldValueChanged, true);
            app.CenterFreqMHzEditField.Value = 8;
            app.DifferenceFreqMHzEditFieldLabel = uilabel(app.FrequencySettingsPanel);
            app.DifferenceFreqMHzEditFieldLabel.Text = 'Difference Freq. [MHz]';
            app.DifferenceFreqMHzEditField = uieditfield(app.FrequencySettingsPanel, 'numeric');
            app.DifferenceFreqMHzEditField.Limits = [0 50];
            app.DifferenceFreqMHzEditField.ValueChangedFcn = createCallbackFcn(app, @DifferenceFreqMHzEditFieldValueChanged, true);
            app.DemodFreqMHzEditFieldLabel = uilabel(app.FrequencySettingsPanel);
            app.DemodFreqMHzEditFieldLabel.Text = 'Demod. Freq. [MHz]';
            app.DemodFreqMHzEditField = uieditfield(app.FrequencySettingsPanel, 'numeric');
            app.DemodFreqMHzEditField.Limits = [0 50];
            app.DemodFreqMHzEditField.ValueChangedFcn = createCallbackFcn(app, @DemodFreqMHzEditFieldValueChanged, true);
            app.DiffFreqShiftlog2SpinnerLabel = uilabel(app.FrequencySettingsPanel);
            app.DiffFreqShiftlog2SpinnerLabel.Text = 'Diff. Freq. Shift [log2]';
            app.DiffFreqShiftlog2Spinner = uispinner(app.FrequencySettingsPanel);
            app.DiffFreqShiftlog2Spinner.Limits = [0 7];
            app.DiffFreqShiftlog2Spinner.ValueChangedFcn = createCallbackFcn(app, @DiffFreqShiftlog2SpinnerValueChanged, true);
            app.DiffFreqShiftlog2Spinner.Value = 3;
            app.UseDemodFreqCheckBox = uicheckbox(app.FrequencySettingsPanel);
            app.UseDemodFreqCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDemodFreqCheckBoxValueChanged, true);
            app.UseDemodFreqCheckBox.Text = 'Use Demod. Freq.';
            app.UseManualFreqCheckBox = uicheckbox(app.FrequencySettingsPanel);
            app.UseManualFreqCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseManualFreqCheckBoxValueChanged, true);
            app.UseManualFreqCheckBox.Text = 'Use Manual Freq.';
            app.UseTCDemodCheckBox = uicheckbox(app.FrequencySettingsPanel);
            app.UseTCDemodCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTCDemodCheckBoxValueChanged, true);
            app.UseTCDemodCheckBox.Text = 'Use TC Demod.';
            app.Amplitude1VEditFieldLabel = uilabel(app.FrequencySettingsPanel);
            app.Amplitude1VEditFieldLabel.Text = 'Amplitude 1 [V]';
            app.Amplitude1VEditField = uieditfield(app.FrequencySettingsPanel, 'numeric');
            app.Amplitude1VEditField.Limits = [0 1];
            app.Amplitude1VEditField.ValueChangedFcn = createCallbackFcn(app, @Amplitude1VEditFieldValueChanged, true);
            app.DisableexttrigCheckBox = uicheckbox(app.FrequencySettingsPanel);
            app.DisableexttrigCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisableexttrigCheckBoxValueChanged, true);
            app.DisableexttrigCheckBox.Text = 'Disable ext. trig';
            app.Amplitude2VEditFieldLabel = uilabel(app.FrequencySettingsPanel);
            app.Amplitude2VEditFieldLabel.Text = 'Amplitude 2 [V]';
            app.Amplitude2VEditField = uieditfield(app.FrequencySettingsPanel, 'numeric');
            app.Amplitude2VEditField.Limits = [0 1];
            app.Amplitude2VEditField.ValueChangedFcn = createCallbackFcn(app, @Amplitude2VEditFieldValueChanged, true);
            app.PhaseCalculationPanel = uipanel(app.PhaseLockControlUIFigure);
            app.PhaseCalculationPanel.Title = 'Phase Calculation';
            app.CICRatelog2SpinnerLabel = uilabel(app.PhaseCalculationPanel);
            app.CICRatelog2SpinnerLabel.Text = 'CIC Rate [log2]';
            app.CICRatelog2Spinner = uispinner(app.PhaseCalculationPanel);
            app.CICRatelog2Spinner.Limits = [7 11];
            app.CICRatelog2Spinner.ValueChangedFcn = createCallbackFcn(app, @CICRatelog2SpinnerValueChanged, true);
            app.CICRatelog2Spinner.Value = 8;
            app.PhasesamplerateHzEditFieldLabel = uilabel(app.PhaseCalculationPanel);
            app.PhasesamplerateHzEditFieldLabel.Text = 'Phase sample rate [Hz]';
            app.PhasesamplerateHzEditField = uieditfield(app.PhaseCalculationPanel, 'numeric');
            app.PhasesamplerateHzEditField.Editable = 'off';
            app.PhasesampleperiodsEditFieldLabel = uilabel(app.PhaseCalculationPanel);
            app.PhasesampleperiodsEditFieldLabel.Text = 'Phase sample period [s]';
            app.PhasesampleperiodsEditField = uieditfield(app.PhaseCalculationPanel, 'numeric');
            app.PhasesampleperiodsEditField.Editable = 'off';
            app.CICShiftSpinnerLabel = uilabel(app.PhaseCalculationPanel);
            app.CICShiftSpinnerLabel.Text = 'CIC Shift';
            app.CICShiftSpinner = uispinner(app.PhaseCalculationPanel);
            app.CICShiftSpinner.Limits = [0 255];
            app.CICShiftSpinner.ValueChangedFcn = createCallbackFcn(app, @CICShiftSpinnerValueChanged, true);
            app.CICShiftSpinner.Value = 24;
            app.LockingparametersPanel = uipanel(app.PhaseLockControlUIFigure);
            app.LockingparametersPanel.Title = 'Locking parameters';
            app.ControlphasedegEditFieldLabel = uilabel(app.LockingparametersPanel);
            app.ControlphasedegEditFieldLabel.Text = 'Control phase [deg]';
            app.ControlphasedegEditField = uieditfield(app.LockingparametersPanel, 'numeric');
            app.ControlphasedegEditField.ValueChangedFcn = createCallbackFcn(app, @ControlphasedegEditFieldValueChanged, true);
            app.KpSpinnerLabel = uilabel(app.LockingparametersPanel);
            app.KpSpinnerLabel.Text = 'Kp';
            app.KpSpinner = uispinner(app.LockingparametersPanel);
            app.KpSpinner.Limits = [0 255];
            app.KpSpinner.ValueDisplayFormat = '%d';
            app.KpSpinner.ValueChangedFcn = createCallbackFcn(app, @KpSpinnerValueChanged, true);
            app.KiSpinnerLabel = uilabel(app.LockingparametersPanel);
            app.KiSpinnerLabel.Text = 'Ki';
            app.KiSpinner = uispinner(app.LockingparametersPanel);
            app.KiSpinner.Limits = [0 255];
            app.KiSpinner.ValueDisplayFormat = '%d';
            app.KiSpinner.ValueChangedFcn = createCallbackFcn(app, @KiSpinnerValueChanged, true);
            app.KdSpinnerLabel = uilabel(app.LockingparametersPanel);
            app.KdSpinnerLabel.Text = 'Kd';
            app.KdSpinner = uispinner(app.LockingparametersPanel);
            app.KdSpinner.Limits = [0 255];
            app.KdSpinner.ValueDisplayFormat = '%d';
            app.KdSpinner.ValueChangedFcn = createCallbackFcn(app, @KdSpinnerValueChanged, true);
            app.DivisorSpinnerLabel = uilabel(app.LockingparametersPanel);
            app.DivisorSpinnerLabel.Text = 'Divisor';
            app.DivisorSpinner = uispinner(app.LockingparametersPanel);
            app.DivisorSpinner.Limits = [0 255];
            app.DivisorSpinner.ValueDisplayFormat = '%d';
            app.DivisorSpinner.ValueChangedFcn = createCallbackFcn(app, @DivisorSpinnerValueChanged, true);
            app.KpRealEditField = uieditfield(app.LockingparametersPanel, 'numeric');
            app.KpRealEditField.Editable = 'off';
            app.KiRealEditField = uieditfield(app.LockingparametersPanel, 'numeric');
            app.KiRealEditField.Editable = 'off';
            app.KdRealEditField = uieditfield(app.LockingparametersPanel, 'numeric');
            app.KdRealEditField.Editable = 'off';
            app.EnableButton = uibutton(app.LockingparametersPanel, 'state');
            app.EnableButton.ValueChangedFcn = createCallbackFcn(app, @EnableButtonValueChanged, true);
            app.EnableButton.Text = 'Enable';
            app.PolarityDropDownLabel = uilabel(app.LockingparametersPanel);
            app.PolarityDropDownLabel.Text = 'Polarity';
            app.PolarityDropDown = uidropdown(app.LockingparametersPanel);
            app.PolarityDropDown.Items = {'Negative', 'Positive'};
            app.PolarityDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarityDropDownValueChanged, true);
            app.PolarityDropDown.Value = 'Positive';
            app.ControlPhaseRadEditField = uieditfield(app.LockingparametersPanel, 'numeric');
            app.ControlPhaseRadEditField.Editable = 'off';
            app.UploadButton = uibutton(app.PhaseLockControlUIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FetchButton = uibutton(app.PhaseLockControlUIFigure, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.UploadonchangeCheckBox = uicheckbox(app.PhaseLockControlUIFigure);
            app.UploadonchangeCheckBox.Text = 'Upload on change';
            % Show the figure after all components are created
            app.PhaseLockControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_PhaseLock(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhaseLockControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhaseLockControlUIFigure)
        end
    end
end